classdef EEG_LLAMAS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        MoreOptionsButton              matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        RecordingName                  matlab.ui.control.EditField
        RecordingNameEditFieldLabel    matlab.ui.control.Label
        ReReferenceToChans             matlab.ui.control.CheckBox
        OpenSettings                   matlab.ui.control.Button
        StopButton                     matlab.ui.control.Switch
        TerminateRecordingSwitchLabel  matlab.ui.control.Label
        text6                          matlab.ui.control.Label
        StimThresh                     matlab.ui.control.EditField
        SetPath                        matlab.ui.control.Button
        UseOld                         matlab.ui.control.CheckBox
        GAC                            matlab.ui.control.CheckBox
        UseTriggers                    matlab.ui.control.CheckBox
        text5                          matlab.ui.control.Label
        PrimaryChannel                 matlab.ui.control.EditField
        UseDownsampling                matlab.ui.control.CheckBox
        text4                          matlab.ui.control.Label
        TargetFS                       matlab.ui.control.EditField
        text3                          matlab.ui.control.Label
        NativeFS                       matlab.ui.control.EditField
        text2                          matlab.ui.control.Label
        numChans                       matlab.ui.control.EditField
        Save                           matlab.ui.control.Button
        Start                          matlab.ui.control.Button
    end

    
    properties (Access = private)
        SettingsDialog % Used to create the sittings dialog menu
     
        SetValues % Used to set delp and movs values
    end
    
    properties (Access = public)
        ScaleSize % scale size in main window per channel, 0 for auto scale
        PreProcessing % cell array of preprocessing functions
        PostProcessing % cell array of postProcessing functions
        FrameRate % rate at which to update main window
        WindowSize % windth of main window is Seconds
        NTR % number of TRs to use for GAC
        trGap % samples per TR
        trMarker % Number used is trigger channel to indicate TR
        NumRefs % number of reference channels to use for Kalman Filter
        RefMode % wheather to check spreadsheet or use
        QParam % Q hyperparamter for kalman filter
        RParam % R hyperparamter for kalman filter
        delpvalue %delp threshold
        movsvalue %movs threshold
        shimpercent %percent of sounds that will be shim (value is from 0 to 1)
        stimblock %length of time stims will be delivered for each block
        BtnMarker % marker value for non-TR events
        VolLevel % volume control 
        confthreshold %threshold for mags value
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function EEG_LLAMAS_OpeningFcn(app)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            app.ScaleSize = 0;
            app.FrameRate = 1;
            app.WindowSize = 10;
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to EEG_LLAMAS (see VARARGIN)
            
            % Choose default command line output for EEG_LLAMAS
            handles.output = hObject;
            handles.Recording = [];
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: Save
        function Save_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Save (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                EEG = handles.Recording;
                uisave('EEG');
                display('Save Completed!')
        end

        % Button pushed function: SetPath
        function SetPath_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to SetPath (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.StreamPath = uigetdir();
            fprintf('Set output stream to %s\n', handles.StreamPath)
            guidata(hObject, handles);
        end

        % Button pushed function: Start
        function Start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Clear our workspace
            
            if exist('port', 'var')
                fclose(port);
            end
        
            if ~isempty(instrfind)
                fclose(instrfind);
            end
            PsychPortAudio('Close');
        
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%  DOUBLE CHECK THESE VARIABLES BEFORE YOU START      %%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        
            vars.UseTriggers = handles.UseTriggers.Value; %whether or not to use triggers and emit sounds
            vars.UseGAC = handles.GAC.Value; %whether or not to use Gradient Artifact Detection
            UseOld = handles.UseOld.Value; %wether to replay an old .mat file instead of collecting new data
            fs = str2num(handles.NativeFS.String); % the native sampling rate
            vars.useDownSampling = handles.UseDownsampling.Value; %determines if we are using downsampling. recommended for new recordings.
            targetFs = str2num(handles.TargetFS.String); %the rate to downsample to
            alphaChannel = str2num(handles.PrimaryChannel.String); %the primary channel to be used to generating triggers
            vars.ReReferenceToChans = app.ReReferenceToChans.Value; % determines if we are using rereferencing
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % add supfolders to path
            addpath('PreprocessingFunctions')
            addpath('PostprocessingFunctions')
            addpath('Recordings')
            addpath('Extras')
            
            % if were doing GAC, we need downsampling, even if its 1:1
            if vars.UseGAC && ~vars.useDownSampling
                vars.useDownSampling = true;
                targetFs = fs;
            end
            % we also need triggers to do GAC
            if vars.UseGAC && ~vars.UseTriggers
                vars.UseTriggers = true;
            end
            
            % check for use of some post-processing functions
            vars.UseKalman = 0;
            vars.UseSlowWaveStim = 0;
            vars.UseAlphaStim = 0;
            for i = 1:length(app.PostProcessing)
                if contains(app.PostProcessing{i}, 'Kalman')
                    vars.UseKalman = 1;
                end
                if contains(app.PostProcessing{i}, 'SlowWavePhasePredict')
                    vars.UseSlowWaveStim = 1;

                end
                if contains(app.PostProcessing{i}, 'AlphaStim')
                    vars.UseAlphaStim = 1;
                end
            end
            
            
            % set scale size var
            vars.ScaleSize = app.ScaleSize;
            
            % check for GPUs
            vars.GPU = gpuDeviceCount;
            if vars.GPU > 0
                gpuDevice;
            end
   
        
            % instantiate the LSL library
            disp('Loading the library...');
            lib = lsl_loadlib();
        
        
            %load eyes open/closed classifier - not used in most
            %implementations
            load('EOECDist.mat', 'ECDist', 'EODist');
            load('EOECModel.mat', 'EOECModel');
            vars.EOECModel = EOECModel;
            
            
            % resolve an LSL stream...
            disp('Resolving an EEG stream...');
            result = {};
            while isempty(result)
                result = lsl_resolve_byprop(lib,'type','EEG');
            end
        
            % create a new inlet
            disp('Opening an inlet...');
            inlet = lsl_inlet(result{1});
        
            % create the channel names - optomized for 64 chan BrainCap
            ChannelNames = {};
            generateLabelNames;
            vars.ChannelNames = ChannelNames;
        
            %set up the trigger box
            if vars.UseTriggers
                try
                    port = serial('COM4');
                    fopen(port);
                catch 
                    warning('WARNING: No COM ports available')
                end
            end
            vars.IntrinsicLag = 20/200; %This is lag associated with sound production in seconds.
        
            % open old data if we've configured the recording to replay old data
            if UseOld
        
                LoadMethod = menu('How would you like to load a file?' , 'From LLAMAS .mat File', 'From raw .mat File', 'From raw .txt file', 'From .mff directory');
                if LoadMethod == 1
                    [filename, pathname] = uigetfile({'*.mat'});
                    load([pathname, filename]);
                    handles.ReplayPath = [pathname, filename];
                    EEG_old = EEG;
                elseif LoadMethod == 2
                    [filename, pathname] = uigetfile({'*.mat'});
                    load([pathname, filename]);
                    handles.ReplayPath = [pathname, filename];
                    EEG_old.Recording = EEG;
                elseif LoadMethod == 3
                    [filename, pathname] = uigetfile({'*.txt'});
                    RawData = readmatrix([pathname, filename]);
                    EEG_old.Recording = RawData;
                    handles.ReplayPath = pathname;
                else
                    pathname = uigetdir();
                    handles.ReplayPath = pathname;
                    EEG_old = LoadMFF(pathname);
                end
                n_old = size(EEG_old.Recording, 1);
                EEG = struct();
                EEG.numChans = size(EEG_old.Recording, 2) - 2;
                dsBuffer2 = 0;
            else
                EEG = struct();  % if we're not using old data, we need to initialze a new EEG struct
                EEG.numChans = str2num(handles.numChans.String); %number of channels (not counting triggers)
            end
        
            numChans = EEG.numChans;
            vars.currentPosition = 1;
            
            EEG.RecordingName = app.RecordingName.Value;
        
            % set up rereferencing to target channels
            if vars.ReReferenceToChans
                ReferenceTable = readmatrix('ReReferenceChannels.csv');
                ReferenceTable = ReferenceTable(:, 2);
                EEG.ReReferenceChans = find(ReferenceTable); 
                EEG.ChansRereference = 1;
            else
                EEG.ChansRereference = 0;
            end
            
            
            %  This block is intended for use if triggers are coming in as
            %  a seperate  stream instead fo as an EEG channel, but it
            %  caused too amny problems, so for now its commented out
        %     if vars.UseTriggers
        %         disp('Resolving a Markers stream...');
        %         result = {};
        %         while isempty(result)
        %             result = lsl_resolve_byprop(lib,'type','Markers');
        %         end
        %         Mrk_info = result{1}.channel_format();
        %
        %         % create a new inlet
        %         disp('Opening an inlet...');
        %         Mrk_inlet = lsl_inlet(result{1});
        %
        %         vals.eventBlockSize = 10000;
        %         vals.eventCount = 1;
        %         EEG.eventTimes = zeros(vals.eventBlockSize, 1);
        %         EEG.eventLbls = cell( vals.eventBlockSize, 1);
        %     end
        
            %Initiate a blank recording
            RecSize = 100000;
            BlockSize = RecSize;
            EEG.Recording = zeros(RecSize, numChans + 1);
            if vars.UseTriggers
                EEG.Recording = zeros(RecSize, numChans + 2);
                EEG.Triggers =  zeros(RecSize, 1);
                EEG.TriggerTypes = zeros(RecSize, 1);
                vars.TriggerIDX = 1;
                vars.TriggerLength = RecSize;
            end
            FullWidth = size(EEG.Recording, 2);
        
            EEG.fs = fs;
            EEG.PrimaryChannel = alphaChannel;
            vars.WindowSize = app.WindowSize; 
        
            %Set up downsampling
            vars.DSrate = EEG.fs/targetFs;
            vars.dsBuffer = 0;
            txtfs = num2str(EEG.fs);
            
            % check OS
            if isunix
                pathDelimiter = '/';
            else
                pathDelimiter = '\';
            end
            
            % open .txt files to write raw data and log files
            txtdate = datestr(datetime('now'));
            txtdate = replace(txtdate, ':', '_');
            txtdate = replace(txtdate, ' ', '_');
            txtdate = replace(txtdate, '-', '_');
            if isfield(handles, 'StreamPath')
                fname = [handles.StreamPath, EEG.RecordingName, '_', txtdate, '_fs_', txtfs, '.txt'];
                logname = [handles.StreamPath, 'LOG_', EEG.RecordingName, '_', txtdate, '_fs_', txtfs, '.txt'];
            else % if the user hasn't set a stream path, use /Recordings as default
                fname = ['Recordings',pathDelimiter, EEG.RecordingName, '_', txtdate, '_fs_', txtfs, '.txt'];
                logname = ['Recordings', pathDelimiter, 'LOG_', EEG.RecordingName, '_', txtdate, '_fs_', txtfs, '.txt'];
        
            end
        
            %set up downsampled recording
            if vars.useDownSampling
                EEG.fs_orig = EEG.fs;
                EEG.fs = targetFs;
                EEG.Recording_orig = single(zeros(RecSize * vars.DSrate, FullWidth));
                DS_FiltSize = round(EEG.fs_orig/100);
                vars.DS_Lowpass = firls(DS_FiltSize, [0, 50, 55, EEG.fs_orig/2]/EEG.fs_orig * 2, [1, 1, 0, 0]); % DS filter
                vars.zi = zeros(length(vars.DS_Lowpass) - 1, numChans);
            else
                EEG.fs_orig = EEG.fs;
            end
        
        
            %set movs,mags and delps threshold
            vars.delpthresh=app.delpvalue;
            vars.movsthresh=app.movsvalue;
            vars.confthreshold=app.confthreshold;
            %set shim percentage
            vars.shimpercent=app.shimpercent;
            %set stim block time
            vars.stimblock=app.stimblock*60*EEG.fs; %convert to same unit as vars.CurrentPositionif vars.currentPosition-vars.stimblock > 0
          
        
            % set up Kalman parameters
            if vars.UseKalman
                 % read kalman data sheet
                KalmanTable = readmatrix('KalmanChannels.csv');
                KalmanTable = KalmanTable(:, 2);
                vars.bcg_idx = KalmanTable == 0;
                eeg_idx = KalmanTable == 1;
                vars.KalmanTargets = find(eeg_idx);
                numKalman = length(vars.KalmanTargets);
                vars.RefMode = app.RefMode;
                
                % set kalman hyper params
                vars.r = find(vars.bcg_idx);
                vars.numReg = length(vars.r);

                if strcmp(vars.RefMode, 'Channel Location')
                    vars.NumRefs = app.NumRefs;
                    vars.RefChans = FindRefChans(vars.KalmanTargets, vars.r, vars.NumRefs);
                    vars.numReg = vars.NumRefs;
                end
                vars.x_hat = zeros(vars.numReg + 1, numKalman);
                vars.R = app.RParam;
                vars.Q = eye(vars.numReg + 1) * app.QParam;
                vars.P = eye(vars.numReg + 1);
                vars.P = repmat(vars.P, 1, 1, numKalman);
                vars.UseKalman = 1;
                EEG.KalmanPrimary = find(vars.KalmanTargets == EEG.PrimaryChannel);
                EEG.Kalman_Signal = zeros(RecSize, numKalman);
                EEG.KalmanTargets = vars.KalmanTargets;
                EEG.KalmanRegressors = vars.r;
                
            else 
                vars.UseKalman = 0;
            end
        
        
            %set up filters
            vars.highPassIIR = designfilt('highpassiir', 'FilterOrder', 4, 'PassbandFrequency', 5, 'SampleRate', EEG.fs);
            BandPass_SlowWave = designfilt('bandpassiir', ...
            'PassbandFrequency1', 0.4, ...
            'Passbandfrequency2', 1.2, ...
            'StopbandFrequency1', 0.01 ,...
            'StopbandFrequency2', 4, ...
            'StopbandAttenuation1', 30, ...
            'StopbandAttenuation2', 30, ...
            'PassbandRipple', 0.1, ...
            'DesignMethod', 'butter', ...
            'SampleRate', EEG.fs);
            [vars.b, vars.a] = tf(BandPass_SlowWave);
        
            %set up our plots, blank for now
            Graphs.Graph = figure('Position', [10, 50, 1150, 900]);
            Graphs.Eyes = figure();
        
            %set up which channels we will actually plot, by reading from the
            %DisplayChannels.csv file
            vars.ChannelsToPlot = readmatrix('DisplayChannels.csv');
            vars.ChannelsToPlot = vars.ChannelsToPlot(:, 2);
            if vars.UseTriggers
                vars.ChannelsToPlot = [vars.ChannelsToPlot; 1]; %make sure our trigger channel is on
            end
            vars.ChannelsToPlot(EEG.PrimaryChannel) = 1; %make sure our primary channel is on
            vars.ChannelsToPlot = vars.ChannelsToPlot == 1; %convert to boolean array
            vars.ChanStr = vars.ChannelNames(vars.ChannelsToPlot);
            if length(vars.ChannelsToPlot) > EEG.numChans
                if vars.UseTriggers &&  length(vars.ChannelsToPlot) > EEG.numChans + 1
                    vars.ChannelsToPlot = [vars.ChannelsToPlot(1:EEG.numChans); 1];
                elseif ~vars.UseTriggers
                    vars.ChannelsToPlot = vars.ChannelsToPlot(1:EEG.numChans);
                end
            end
            vars.ChannelsToPlot = vars.ChannelsToPlot == 1; %convert to boolean array
            vars.ChannelsToPlotdIDX = find(vars.ChannelsToPlot);
            vars.numChannelsToPlot = sum(vars.ChannelsToPlot);

        
            %determine the bin numbers of the frequencies of interest
            [~, bins] = pwelch(rand(EEG.fs,1), [], [], [], EEG.fs);
            [~, vars.fullMin] = min(abs(bins - 3));
            [~, vars.fullMax] = min(abs(bins - 30));
            [~, vars.alphaMin] = min(abs(bins - 8));
            [~, vars.alphaMax] = min(abs(bins - 12));
        
            %set up when and how often we'll update our plot
            vars.EEGPlotPosition = 0.6 * EEG.fs;
            vars.EEGPlotPerSecond = app.FrameRate;
        
            %set up how often we will check for trigger opportunities for
            %alpha stim. Not used in most implementations
            vars.alphaPosition = 5 * EEG.fs;
            vars.alphaPerSecond = 10;
            vars.alphaVec = zeros(1000 * vars.alphaPerSecond, 1);
            vars.sawTooth = linspace(0,1,5);
            vars.smoothedAlphaVec = zeros(1000 * vars.alphaPerSecond, 1);
            vars.pEyesClosed = zeros(1000 * vars.alphaPerSecond, 1);
        
            
            %initialize the audio player
            InitializePsychSound(1);
            vars.audioDevices = PsychPortAudio('GetDevices');
            if isunix
                vars.deviceID = 0;
            else
                vars.deviceID = 5;
            end
            
            
            %build the sound we'll play
            vars.fsSound = vars.audioDevices(vars.deviceID + 1).DefaultSampleRate; %48000;
            s = 0.05;
            cn = dsp.ColoredNoise(1, vars.fsSound * s, 1, 'OutputDataType', 'double');
            PinkSound = cn();
            PinkSound = hamming(length(PinkSound)).*PinkSound;
        
            vars.Volume = app.VolLevel;
            vars.audio_port = PsychPortAudio('Open', vars.deviceID, 1, 3, vars.fsSound, 2, []);
            PsychPortAudio('Stop', vars.audio_port);
            audio_to_play = [PinkSound'; PinkSound'] * vars.Volume;
            PsychPortAudio('FillBuffer', vars.audio_port, audio_to_play);
            vars.waitForDeviceToStart = 1;
            vars.repetitions = 1;
            
            %Initialize storage of our chunk sizes, so we can check latency
            EEG.ChunkSizes = zeros(1000000, 1);
            EEG.Latency = zeros(1000000, 1);
            count = 1;
        
            %Initialize our slow wave stim variables, not need if you're not doing slow wave stim 
            TriggerThreshold = str2num(handles.StimThresh.String);
            EEG.Threshold = TriggerThreshold;
            vars.LastStimPosition = 0;
            vars.z = zeros(max(length(vars.a),length(vars.b))-1, 1); %filter initial conditions
            vars.TriggerBuffer = EEG.fs;
            vars.SlowWaveDelay = 0.001;
            vars.StimTimes = zeros(20000, 1);
            vars.StimCount = 1;
        
            %Set up Keyboard Reader
            try
                KbName('UnifyKeyNames')
            catch 
                warning('resolving error w/ kbNames...')
            end
            
            try
                [i,j] = GetKeyboardIndices();
               KB_scanner = i(find(strcmp(j, 'Current Designs, Inc. 932'))); % find index of scanner kb
               if isempty(KB_scanner)
                   KB_scanner = -3;
               end
               % KB_task = -3;% use -3 to catch all keyboards, or use somthing like:
                KB_task = KB_scanner; % to get just the scanner kb
               %  i(find(strcmp(j, 'Keyboard')));
               % to find a specific keyboard
                KbName('UnifyKeyNames');
                KbQueueCreate(KB_task);
                KbQueueStart(KB_task);
                EEG.KeyPresses = zeros(50000, 256);
                vars.KeyboardTracker = true;
            catch
                warning('Keyboard Tracker not loaded!')
                vars.KeyboardTracker = false;
            end
        
            %Set up file for log and raw data
            FileID = fopen(fname, 'w');
            LogID = fopen(logname, 'w');
            fspec = repmat('%-3.1f,', [1, FullWidth]);
            fspec = [fspec(1:end - 5), '11.7f\r\n'];
            %write to log file
            vars.ntr = app.NTR;
            vars.trGap = app.trGap;
            vars.trMarker = app.trMarker;
            vars.BtnMarker = app.BtnMarker;
            WriteLogInfo(handles, LogID, txtdate, vars, EEG);
        
            %now we're ready to start receiving data
            disp('Now receiving chunked data...');
            vars.currentPosition_orig = 1;
            vars.TrigTime = zeros(5, 1);
            vars.TrigCount = 1;
            AssignTime = 0;
            
            if UseOld
                Old_position = 1;
            end
            BreakFlag = 0;
            %cleanupObj = onCleanup(@cleanUpTime);
            app.StopButton.Value = 'Go';
            EEG.StartTime = GetSecs();
            vars.startClock = tic;
            vars.debugTimes = zeros(10000, 4);
            vars.debugCount = 0;
            while (isgraphics(Graphs.Eyes) && isgraphics(Graphs.Graph)) && ~BreakFlag
                if strcmp(app.StopButton.Value, 'Stop')
                    BreakFlag = 1;
                end
                vars.debugCount = vars.debugCount + 1;
                vars.clock = tic; %start a timer
                % get chunk from the inlet
                t_offset = toc(vars.startClock);
                [vars.OrigChunk, vars.stamps] = inlet.pull_chunk();
                vars.ChunkTime = GetSecs();
                
                % this block is used for a seperate marker stream, but does
                % not work currently
        %         if vars.UseTriggers
        %             [mrks,ts] = Mrk_inlet.pull_sample(0.00005);
        %
        %             if length(ts) > 0
        %                 if length(ts) + vals.eventCount > length(EEG.eventLbls)
        %                     EEG.eventTimes = cat(1, EEG.eventTimes, zeros(vals.eventBlockSize, 1));
        %                     EEG.eventLbls = cat(1, EEG.eventLbls, cell(vals.eventBlockSize, 1));
        %                 end
        %                 EEG.eventTimes(vals.eventCount:vals.eventCount + length(ts) - 1) = ts;
        %                 EEG.eventLbls(vals.eventCount:vals.eventCount + length(ts) - 1) = mrks;
        %                 vals.eventCount = vals.eventCount + length(ts);
        %             end
        %         end
        
        
                [vars.SamplesInChunk, vars.ChansInChunk] = size(vars.OrigChunk');
        
                if numel(vars.OrigChunk) > 0 %if the chunk isn't empty, we need to process it.
                    %vars.debugTimes(vars.debugCount, 1)  = toc(vars.clock);
                    
                    if strcmp(app.StopButton.Value, 'Stop')
                        BreakFlag = 1;
                    end
                    % if we're using old data, we will process that instead
                    % of the chunk we recieved
                    if (UseOld && n_old > Old_position + vars.SamplesInChunk) && (vars.UseGAC || vars.useDownSampling)
                        vars.stamps = downsample(vars.stamps, round(5000/EEG.fs_orig), dsBuffer2);
                        [vars.OrigChunk, dsBuffer2] = DownSampleTriggrs(vars.OrigChunk, round(5000/EEG.fs_orig), dsBuffer2);
        
                        [vars.SamplesInChunk, vars.ChansInChunk] = size(vars.OrigChunk');
                        vars.OrigChunk = [EEG_old.Recording(Old_position:Old_position + vars.SamplesInChunk - 1, 1:numChans)'; vars.OrigChunk(end, :)];
                        if vars.UseGAC %preserve trs from original
                            vars.OrigChunk(end, find(EEG_old.Recording(Old_position:Old_position + vars.SamplesInChunk - 1, numChans + 1) == vars.trMarker)) = vars.trMarker;
                        end
                        vars.OrigChunk(end, find(EEG_old.Recording(Old_position:Old_position + vars.SamplesInChunk - 1, numChans + 1) == vars.BtnMarker)) = vars.BtnMarker;
                        [vars.SamplesInChunk, vars.ChansInChunk] = size(vars.OrigChunk');
                        Old_position = Old_position + vars.SamplesInChunk;
                    end
                    %If the old recording is done, break
                    if (UseOld && n_old < Old_position + 5 * vars.SamplesInChunk)
                        BreakFlag = 1;
                    end
                    % write raw data to file
                    fprintf(FileID, fspec, [vars.OrigChunk', vars.stamps']');
                    %vars.debugTimes(vars.debugCount, 1)  = toc(vars.clock);
                    % store trigger locations and values
                    if vars.UseTriggers
                        trigidx = find(vars.OrigChunk(end, :) > 0);
                        trigmrks = vars.OrigChunk(end, trigidx);
                        
                        % add extraspace in EEG.Trigger andTriggerMrks if
                        % needed
                        if length(EEG.Triggers) <= vars.TriggerIDX + length(trigidx)
                            EEG.Triggers = [EEG.Triggers, zeros(BlockSize, 1)];
                            EEG.TriggerTypes = [EEG.TriggerTypes, zeros(BlockSize, 1)];
                            vars.TriggerLength =  vars.TriggerLength + BlockSize;
                        end
                        %vars.debugTimes(vars.debugCount, 2)  = toc(vars.clock);
                        if vars.useDownSampling
                            EEG.Triggers(vars.TriggerIDX:vars.TriggerIDX + length(trigidx) - 1) = ...
                                trigidx + vars.currentPosition_orig - 1;
                        else
                            EEG.Triggers(vars.TriggerIDX:vars.TriggerIDX + length(trigidx) - 1) = ...
                                trigidx + vars.currentPosition - 1;
                        end
                        EEG.TriggerTypes(vars.TriggerIDX:vars.TriggerIDX + length(trigidx) - 1) = ...
                            trigmrks;
                        vars.TriggerIDX = vars.TriggerIDX + length(trigidx);
                        
                    end
                    %downsample the chunk
                    %vars.debugTimes(vars.debugCount, 2)  = toc(vars.clock);
                    if vars.useDownSampling
        
                        % convert to single precision to save memory -
                        % given the resolution of the amps, double
                        % precision is not needed
                        
                        EEG.Recording_orig(vars.currentPosition_orig:vars.currentPosition_orig + vars.SamplesInChunk - 1, :)...
                            = single([vars.OrigChunk', vars.stamps']);
        
                        vars.currentPosition_orig = vars.currentPosition_orig + vars.SamplesInChunk;
                       
                        % Perform Gradient artifact correction
                        vars = GAC(EEG, vars);
        
                        %apply downsapmling filter
                        if vars.UseTriggers
                            
                           [O, vars.zi] = filter(vars.DS_Lowpass, 1, vars.OrigChunk(1:end - 1, :)', vars.zi, 1);
                           vars.OrigChunk(1:end - 1, :) = O';
                        else
                            [O, vars.zi] = filter(vars.DS_Lowpass, 1, vars.OrigChunk', vars.zi, 1);
                            vars.OrigChunk = O';
                        end
                        
                        %down sampling works slightly differently if we're using
                        %triggers
                        if vars.UseTriggers
                            %timestamps are downsampled in the usual way
                            vars.stamps = downsample(vars.stamps', vars.DSrate, vars.dsBuffer)';
        
                            %triggers must be downsampled using this specialized
                            %function
                            [vars.chunk, vars.dsBuffer] = DownSampleTriggrs(vars.OrigChunk, vars.DSrate, vars.dsBuffer);
        
                            [vars.SamplesInChunk, vars.ChansInChunk] = size(vars.chunk');
                            if vars.SamplesInChunk ~= length(vars.stamps)
                                vars.stamps = zeros(1, vars.SamplesInChunk);
                            end
                        else
        
                            %data without triggers can be downsampled normally
                            if vars.SamplesInChunk == 1 && (vars.dsBuffer == 0)
                                vars.chunk = vars.OrigChunk;
                            elseif vars.SamplesInChunk == 1
                                vars.chunk = zeros(vars.ChansInChunk, 0);
                            else
                                vars.chunk = downsample(vars.OrigChunk', vars.DSrate, vars.dsBuffer)';
                            end
                            vars.stamps = downsample(vars.stamps', vars.DSrate, vars.dsBuffer)';
                            vars.dsBuffer = vars.DSrate - mod(vars.SamplesInChunk - vars.dsBuffer, vars.DSrate);
                            if vars.dsBuffer == vars.DSrate
                                vars.dsBuffer = 0;      
                            end
                            [vars.SamplesInChunk, ~] = size(vars.chunk');
                        end
                        
                        %perform rereferencing if requested
                        if vars.ReReferenceToChans
                            [vars, Graphs, EEG] = ReReferenceToChans(EEG, vars, Graphs);
                        end
                    else
                        
                        if vars.ReReferenceToChans
                            [vars, Graphs, EEG] = ReReferenceToChans(EEG, vars, Graphs);
                        end
                        vars.chunk = vars.OrigChunk;
                    end
                    %vars.debugTimes(vars.debugCount, 2)  = toc(vars.clock);
                    % if using old data, and no GAC, perform downsampling
                    % here
                    
                    % I'm no longer sure why this IF block needs to exist,
                    % and it seems to cause problems. Commenting it out for
                    % now 1/31/23 JL
%                     if (UseOld && (n_old > Old_position + vars.SamplesInChunk)) && ~vars.UseGAC
%                         if EEG.fs < 5000
%                             vars.stamps = downsample(vars.stamps', 5000/EEG.fs, vars.dsBuffer)';
%         
%                             %triggers must be downsampled using this specialized
%                             %function
%                             [vars.chunk, vars.dsBuffer] = DownSampleTriggrs(vars.OrigChunk, 5000/EEG.fs, vars.dsBuffer);
%         
%                             [vars.SamplesInChunk, vars.ChansInChunk] = size(vars.chunk');
%                             if vars.SamplesInChunk ~= length(vars.stamps)
%                                 vars.stamps = zeros(1, vars.SamplesInChunk);
%                             end
%                         end
%         
%                         vars.chunk = [EEG_old.Recording(Old_position:Old_position + vars.SamplesInChunk - 1, 1:numChans)'; 
%                             vars.chunk(end, :)];
%         
%                         Old_position = Old_position + vars.SamplesInChunk;
%                     end
                    
                    %add extra space in our recoding if necesary
                    if vars.currentPosition + vars.SamplesInChunk >= RecSize
        
                        RecSize = RecSize + BlockSize;
                        if vars.UseKalman
                            EEG.Kalman_Signal = cat(1, EEG.Kalman_Signal, zeros(BlockSize, numKalman));
                        end
                        if vars.useDownSampling
                            EEG.Recording = cat(1, EEG.Recording, zeros(BlockSize, FullWidth));
                            chop = max(1, vars.currentPosition_orig - EEG.fs_orig * 120);
                            EEG.Recording_orig = cat(1, EEG.Recording_orig(chop:end, :), ...
                                single(zeros(BlockSize * vars.DSrate, FullWidth)));
                            vars.currentPosition_orig = vars.currentPosition_orig - (chop - 1);
                        else
                            EEG.Recording = cat(1, EEG.Recording, zeros(BlockSize, FullWidth));
                        end
                    end
        

                    % insert new data into recording
                    EEG.Recording(vars.currentPosition:vars.currentPosition + vars.SamplesInChunk - 1, :) = [vars.chunk', vars.stamps'];
                    %vars.debugTimes(vars.debugCount, 3)  = toc(vars.clock);
                    %update our position
                    vars.currentPosition = vars.currentPosition + vars.SamplesInChunk;
        
                    %keep track of chunk sizes
                    EEG.ChunkSizes(count) = vars.SamplesInChunk;
                    count = count + 1;
        
                    %periodically send our data to the base workspace so we don't
                    %lose it in the event of an error (roughly once a minute)
                    %Note that this substantially increases the RAM requirements -
                    %might be usefult to have an option to disable this
                    if vars.currentPosition > AssignTime
                        EEG.vars = vars;
                        assignin('base', 'EEG', EEG);
                        AssignTime = AssignTime + 30 * EEG.fs;
                    end
        
                    %Read in keyboard presses every 10 chunks
                    if vars.KeyboardTracker
                        if mod(count, 10) == 0
                            [~, firstpress] = KbQueueCheck(KB_task);
                            EEG.KeyPresses(count / 10, :) = firstpress;
                            vars.KeyboardIDX = (count / 10);
                        end
                    end
        
                    %Play a train of sounds at the very beginning just to make sure
                    %everything is working
                    if sum(count == [10, 30, 50, 70, 90]) > 0
                        PsychPortAudio('Start', vars.audio_port, vars.repetitions, GetSecs(), 0);
                        vars.TrigTime(vars.TrigCount) = toc(vars.startClock);
                        vars.TrigCount = vars.TrigCount  + 1;
                    end
                    
                    % loop through post-processing function calls
                    for ppf = 1:length(app.PostProcessing)
                        [vars, Graphs, EEG] = feval(app.PostProcessing{ppf}(1:end-2), EEG, vars, Graphs);                        
                    end
                    EEG.Latency(count - 1) = toc(vars.clock);
                    %vars.debugTimes(vars.debugCount, 4)  = toc(vars.clock);
                    % this block catchesthe stop signal. we need it to be
                    % in a few places to catch all potential stops
                    if strcmp(app.StopButton.Value, 'Stop')
                        BreakFlag = 1;
                    end
                end
        
                %This pause seems to be required, or the while loop will never break. I'm not really sure why. You can
                %shorten it if needed, but 3msec is inconsiquential for most
                %purposes
                pause(0.001)
                if strcmp(app.StopButton.Value, 'Stop')
                    BreakFlag = 1;
                end
            end
            cleanUpTime();
            
            
            function cleanUpTime()
                vars.TrigTime
                disp('Halting recording...')
                fprintf('\n the TRGap is: %d\n', vars.trGap)
                EEG.ChannelNames = vars.ChannelNames;
                
                % trim trailing 0s
                EEG.Recording = EEG.Recording(1:vars.currentPosition, :);
                if vars.UseKalman
                    EEG.Kalman_Signal = EEG.Kalman_Signal(1:vars.currentPosition, :);
                end
                if vars.UseTriggers && vars.TriggerIDX > 1
                    EEG.Triggers = EEG.Triggers(1:vars.TriggerIDX - 1);
                    EEG.TriggerTypes = EEG.TriggerTypes(1:vars.TriggerIDX - 1);
                end
                EEG.ChunkSizes = EEG.ChunkSizes(1:count);
                EEG.StimTimes = vars.StimTimes(1:vars.StimCount);
                EEG.trGap = vars.trGap;
                
                % attach some of the workspace variables to our EEG var
                EEG.vars = vars;
                EEG.PostProcessing = app.PostProcessing;
            
                % send EEG to base workspace, and also do a UISave for
                % redundancy
                assignin('base', 'EEG', EEG);
                uisave('EEG')
            
                % attach EEG to out app handles
                handles.Recording = EEG;
                guidata(hObject, handles);
            
                %close stuff
            
                fclose(FileID);
                fclose(LogID);
    
            
                [~, n] = size(EEG.Recording);
                EEG.StartTime = EEG.Recording(1, n); %record the start time from the stamps column of our recording
                if vars.KeyboardTracker
                    KbQueueRelease(KB_task);
                end
            
                PsychPortAudio('Close')
                delete(inlet);
            %     delete(Mrk_inlet);
                if vars.UseTriggers
                    try
                        fclose(port);
                    catch
                        warning('No Port detected, so no port was closed')
                    end
                end


                %store our data
                EEG.ChannelNames = vars.ChannelNames;
                assignin('base', 'vars', vars);
                guidata(hObject, handles);
                disp('transmission completed')
                
                
                % this can be ignored. it was an experimental way to reccomend
                % a trigger threshold
                FiltSignal = filter(vars.b, vars.a, EEG.Recording(:, 18));
                if length(FiltSignal) > 1000
                    pks = findpeaks(FiltSignal);
                    pks = sort(pks, 'descend');
            
                    if length(pks) >= 10
                        meanPkHeight = mean(pks(2:10));
                        display(['The mean peak height is: ', num2str(meanPkHeight)])
                        display(['The reccomended Threshold is: ', num2str(meanPkHeight * 1.4)])
                    end
                end
                BreakFlag = 1;
            end
        end

        % Button pushed function: OpenSettings
        function OpenSettingsButtonPushed(app, event)
            app.SettingsDialog = SettingsDialog(app);
        end

        % Button pushed function: MoreOptionsButton
        function MoreOptionsButtonPushed(app, event)
            app.MoreOptionsButton.Enable='off';
            app.SetValues= SetValues(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'EEG_LLAMAS';
            app.figure1.Tag = 'figure1';
            app.Start = uibutton(app.figure1, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @Start_Callback, true);
            app.Start.Tag = 'Start';
            app.Start.Text = 'Begin Recording';
            app.Save = uibutton(app.figure1, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @Save_Callback, true);
            app.Save.Tag = 'Save';
            app.Save.Text = 'Save Recording';
            app.numChans = uieditfield(app.figure1, 'text');
            app.numChans.Tag = 'numChans';
            app.numChans.Value = '64';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Number of Channels';
            app.NativeFS = uieditfield(app.figure1, 'text');
            app.NativeFS.Tag = 'NativeFS';
            app.NativeFS.Value = '5000';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Native Sample Rate';
            app.TargetFS = uieditfield(app.figure1, 'text');
            app.TargetFS.Tag = 'TargetFS';
            app.TargetFS.Value = '200';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Target Sample Rate';
            app.UseDownsampling = uicheckbox(app.figure1);
            app.UseDownsampling.Tag = 'UseDownsampling';
            app.UseDownsampling.Text = 'Use Down Sampling';
            app.UseDownsampling.Value = true;
            app.PrimaryChannel = uieditfield(app.figure1, 'text');
            app.PrimaryChannel.Tag = 'PrimaryChannel';
            app.PrimaryChannel.Value = '17';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Primary Channel';
            app.UseTriggers = uicheckbox(app.figure1);
            app.UseTriggers.Tag = 'UseTriggers';
            app.UseTriggers.Text = 'Use Triggers';
            app.UseTriggers.Value = true;
            app.GAC = uicheckbox(app.figure1);
            app.GAC.Tag = 'GAC';
            app.GAC.Text = 'Use GAC';
            app.UseOld = uicheckbox(app.figure1);
            app.UseOld.Tag = 'UseOld';
            app.UseOld.Text = 'Use Old Data';
            app.SetPath = uibutton(app.figure1, 'push');
            app.SetPath.ButtonPushedFcn = createCallbackFcn(app, @SetPath_Callback, true);
            app.SetPath.Tag = 'SetPath';
            app.SetPath.Text = 'Set Path';
            app.StimThresh = uieditfield(app.figure1, 'text');
            app.StimThresh.Tag = 'StimThresh';
            app.StimThresh.Value = '0.9';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Slow Stim Threshold';
            app.TerminateRecordingSwitchLabel = uilabel(app.figure1);
            app.TerminateRecordingSwitchLabel.Text = 'Terminate Recording';
            app.StopButton = uiswitch(app.figure1, 'slider');
            app.StopButton.Items = {'Stop', 'Go'};
            app.StopButton.Value = 'Stop';
            app.OpenSettings = uibutton(app.figure1, 'push');
            app.OpenSettings.ButtonPushedFcn = createCallbackFcn(app, @OpenSettingsButtonPushed, true);
            app.OpenSettings.Text = 'Open Settings';
            app.ReReferenceToChans = uicheckbox(app.figure1);
            app.ReReferenceToChans.Tag = 'GAC';
            app.ReReferenceToChans.Text = 'Re-reference to Channels';
            app.RecordingNameEditFieldLabel = uilabel(app.figure1);
            app.RecordingNameEditFieldLabel.Text = 'Recording Name';
            app.RecordingName = uieditfield(app.figure1, 'text');
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = 'logo.jpg';
            app.MoreOptionsButton = uibutton(app.figure1, 'push');
            app.MoreOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @MoreOptionsButtonPushed, true);
            app.MoreOptionsButton.Text = 'More Options';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG_LLAMAS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @EEG_LLAMAS_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end