classdef SetValues < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ConfidenceThresholdEditField  matlab.ui.control.NumericEditField
        ConfidenceThresholdEditFieldLabel  matlab.ui.control.Label
        LengthofStimBlocksinMinuteschoose0ifnoneEditField  matlab.ui.control.NumericEditField
        LengthofStimBlocksinMinuteschoose0ifnoneEditFieldLabel  matlab.ui.control.Label
        PercentofShimSoundsChooseValuefrom0to1EditField  matlab.ui.control.NumericEditField
        PercentofShimSoundsChooseValuefrom0to1EditFieldLabel  matlab.ui.control.Label
        SetButton                     matlab.ui.control.Button
        MovsThresholdEditField        matlab.ui.control.NumericEditField
        MovsThresholdEditFieldLabel   matlab.ui.control.Label
        DelpThresholdEditField        matlab.ui.control.NumericEditField
        DelpThresholdEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % the main app, EEG_LLAMAS
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.CallingApp= caller;
           
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.CallingApp.delpvalue= app.DelpThresholdEditField.Value;
            app.CallingApp.movsvalue= app.MovsThresholdEditField.Value;
            app.CallingApp.shimpercent=app.PercentofShimSoundsChooseValuefrom0to1EditField.Value;
            app.CallingApp.stimblock=app.LengthofStimBlocksinMinuteschoose0ifnoneEditField.Value;
            app.CallingApp.confthreshold=app.ConfidenceThresholdEditField.Value;
            fprintf('\n Delp= %d, Movs=%d,Conf=%.1f, Shim sounds will be delivered %d percent of the time\n Stim blocks of %d minutes',app.CallingApp.delpvalue,app.CallingApp.movsvalue,app.CallingApp.confthreshold,app.CallingApp.shimpercent*100, app.CallingApp.stimblock)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.MoreOptionsButton.Enable='on';
            delete(app)
            
        end

        % Callback function
        function MovsThresholdEditFieldValueChanged(app, event)
    
            
        end

        % Callback function
        function DelpThresholdEditFieldValueChanged(app, event)
         
        end

        % Callback function
        function PercentofShimSoundsChooseValuefrom0to1EditFieldValueChanged(app, event)
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DelpThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.DelpThresholdEditFieldLabel.Text = 'Delp  Threshold';
            app.DelpThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.DelpThresholdEditField.Limits = [0 Inf];
            app.DelpThresholdEditField.Value = 7;
            app.MovsThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.MovsThresholdEditFieldLabel.Text = 'Movs  Threshold';
            app.MovsThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.MovsThresholdEditField.Limits = [0 Inf];
            app.MovsThresholdEditField.Value = 4;
            app.SetButton = uibutton(app.UIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.PercentofShimSoundsChooseValuefrom0to1EditFieldLabel = uilabel(app.UIFigure);
            app.PercentofShimSoundsChooseValuefrom0to1EditFieldLabel.Text = 'Percent of Shim Sounds (Choose Value from 0 to 1)';
            app.PercentofShimSoundsChooseValuefrom0to1EditField = uieditfield(app.UIFigure, 'numeric');
            app.PercentofShimSoundsChooseValuefrom0to1EditField.Limits = [0 1];
            app.LengthofStimBlocksinMinuteschoose0ifnoneEditFieldLabel = uilabel(app.UIFigure);
            app.LengthofStimBlocksinMinuteschoose0ifnoneEditFieldLabel.Text = 'Length of Stim Blocks  in Minutes (choose  0 if none)';
            app.LengthofStimBlocksinMinuteschoose0ifnoneEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthofStimBlocksinMinuteschoose0ifnoneEditField.Limits = [0 Inf];
            app.ConfidenceThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ConfidenceThresholdEditFieldLabel.Text = 'Confidence Threshold';
            app.ConfidenceThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConfidenceThresholdEditField.Limits = [0 Inf];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetValues(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end